classdef Proyecto1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AcquisitionTab                  matlab.ui.container.Tab
        TimeEditField_2                 matlab.ui.control.NumericEditField
        TimeEditField_2Label            matlab.ui.control.Label
        GeneratefromDACButton           matlab.ui.control.Button
        SinteticsLabel                  matlab.ui.control.Label
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        PathEditField                   matlab.ui.control.EditField
        PathLabel                       matlab.ui.control.Label
        SignalsListBox                  matlab.ui.control.ListBox
        SignalsListBoxLabel             matlab.ui.control.Label
        PhaseSpinner                    matlab.ui.control.Spinner
        PhaseSpinnerLabel               matlab.ui.control.Label
        FinalFrequencyHzSpinner         matlab.ui.control.Spinner
        FinalFrequencyHzSpinnerLabel    matlab.ui.control.Label
        InitialFrequencyHzSpinner       matlab.ui.control.Spinner
        InitialFrequencyHzSpinnerLabel  matlab.ui.control.Label
        AmplitudeSpinner                matlab.ui.control.Spinner
        AmplitudeSpinnerLabel           matlab.ui.control.Label
        TimesSpinner                    matlab.ui.control.Spinner
        TimesSpinnerLabel               matlab.ui.control.Label
        SamplingFrequencyEditField      matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        TypeofsignalDropDown            matlab.ui.control.DropDown
        TypeofsignalDropDownLabel       matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        GenerateButton                  matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        SelectionFileButton             matlab.ui.control.Button
        RecordButton                    matlab.ui.control.Button
        DACNationalInstrumentLabel      matlab.ui.control.Label
        ImportFileLabel                 matlab.ui.control.Label
        RecordLabel                     matlab.ui.control.Label
        DataAcquisitionLabel            matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        FilteringTab                    matlab.ui.container.Tab
        FilterTypeDropDownLabel         matlab.ui.control.Label
        FilterTypeDropDown              matlab.ui.control.DropDown
        SizeWindowEditField             matlab.ui.control.NumericEditField
        SizeWindowEditFieldLabel        matlab.ui.control.Label
        IIRakbkButton                   matlab.ui.control.Button
        FIRhnButton                     matlab.ui.control.Button
        StopOutputButton                matlab.ui.control.Button
        PlayOutputButton_2              matlab.ui.control.Button
        GenerateButton_5                matlab.ui.control.Button
        StopSignalButton                matlab.ui.control.Button
        PlaySignalButton                matlab.ui.control.Button
        SizeSignalEditField             matlab.ui.control.NumericEditField
        SizeSignalEditFieldLabel        matlab.ui.control.Label
        FrequencySignalEditField        matlab.ui.control.NumericEditField
        FrequencySignalEditFieldLabel   matlab.ui.control.Label
        SignalDropDown                  matlab.ui.control.DropDown
        SignalLabel                     matlab.ui.control.Label
        UIAxes9_2                       matlab.ui.control.UIAxes
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        FourierTab_2                    matlab.ui.container.Tab
        SpectrumOUTButton               matlab.ui.control.Button
        SpectumINButton                 matlab.ui.control.Button
        FourierTransformButton          matlab.ui.control.Button
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes5_2                       matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        ProcessingTab                   matlab.ui.container.Tab
        SaveOutputButton                matlab.ui.control.Button
        PlayOutputButton                matlab.ui.control.Button
        StopButton_2                    matlab.ui.control.Button
        ReversePlayButton               matlab.ui.control.Button
        PlayButton_3                    matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        PreprocessTab                   matlab.ui.container.Tab
        Label_4                         matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.NumericEditField
        TimeEditFieldLabel              matlab.ui.control.Label
        NormalizeButton                 matlab.ui.control.Button
        DelayButton                     matlab.ui.control.Button
        DisplaceinthetimeLabel          matlab.ui.control.Label
        AdvanceButton                   matlab.ui.control.Button
        OperationsTab                   matlab.ui.container.Tab
        ResamplingButton                matlab.ui.control.Button
        Label_6                         matlab.ui.control.Label
        FrecuencyEditField              matlab.ui.control.NumericEditField
        FrecuencyEditFieldLabel         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        ResizeButton                    matlab.ui.control.Button
        MultiplySignalsButton           matlab.ui.control.Button
        AddSignalsButton                matlab.ui.control.Button
        AdjustVolumeTab                 matlab.ui.container.Tab
        AdjustVolumeLinearlyButton      matlab.ui.control.Button
        LinearSwitch                    matlab.ui.control.RockerSwitch
        LinearSwitchLabel               matlab.ui.control.Label
        ConstantKnob                    matlab.ui.control.Knob
        ConstantKnobLabel               matlab.ui.control.Label
        MonoStereoTab                   matlab.ui.container.Tab
        GenerateButton_4                matlab.ui.control.Button
        TwoSignalsMonotoStereoLabel     matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        OneSignalLabel                  matlab.ui.control.Label
        GenerateButton_3                matlab.ui.control.Button
        MonotoStereoLabel               matlab.ui.control.Label
        StereotoMonoLabel               matlab.ui.control.Label
        GenerateButton_2                matlab.ui.control.Button
        VerifyNANINFTab                 matlab.ui.container.Tab
        EditField                       matlab.ui.control.EditField
        EliminateButton                 matlab.ui.control.Button
        INFButton                       matlab.ui.control.Button
        NANButton                       matlab.ui.control.Button
        UITable4                        matlab.ui.control.Table
        UITable3                        matlab.ui.control.Table
        Label_3                         matlab.ui.control.Label
        SourceDropDown                  matlab.ui.control.DropDown
        SourceDropDownLabel             matlab.ui.control.Label
        Signal2DropDown_2               matlab.ui.control.DropDown
        Signal2Label                    matlab.ui.control.Label
        Signal1DropDown_2               matlab.ui.control.DropDown
        Signal1Label_2                  matlab.ui.control.Label
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        AboutTab                        matlab.ui.container.Tab
        Label_8                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Nov2023Label                    matlab.ui.control.Label
        CaliColombiaLabel               matlab.ui.control.Label
        UniversidaddelValleLabel        matlab.ui.control.Label
        LuisEduardoRodriguezOrtiz1940527Label  matlab.ui.control.Label
        MillerEduardoOrtizMelo1746911Label  matlab.ui.control.Label
        LisdeyFabianaPeaBohorquez1840838Label  matlab.ui.control.Label
        stProjectDigitalSignalProcessingLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        x=0;
        numarch=1;
        numgrab=1;
        numsinte=1;
        numgener=1;
        numADQ=1;
        selec=0;  
        %signals = zeros(1000 , 3 , 10, "double");
        signals = cell(1,10);
        frec = zeros(10);
        n = cell(1,10);
        proce = cell(1,3);
        frecproce = zeros(3);
        nproce = cell(1,3);
        player;
        s1;
        s2;
        signal1;
        signal2;
        sfil;
        sproce1;
        sproce2;
        akbk = cell(1,2);
        fourier = cell(1,2);
        %norma = zeros(1000 , 2);
    end
    
    methods (Access = private)
        
        function an = normalizar(app, a)
            mins = min(a);
            a1 = a-mins;

            maxa1 = max(a1);
            a2 = a1/maxa1;
            a3 = a2*2;
            an = a3-1;
        end

        function nd = desplazar(app, k, ni, nf, op)
            if op == "adelanto"
                nd = (ni + k):(nf + k);
            elseif op == "retraso"
                nd = (ni - k):(nf - k);
            end
        end
        function [ni, nf] = pos(app, ni, s)
            Lx = length(s);
            nf = Lx + ni - 1;
        end
        
        function res = convo(app,x,nx,h,nh,Lxb)
            Lx = length(x);
            Lh = length(h);

            ni_x = nx(1);
            nf_x = nx(end);
            ni_h = nh(1);
            nf_h = nh(end);

            Ly=Lx + Lh-1;
            ni_y=ni_x+ni_h;
            nf_y=nf_x+nf_h;

            y=zeros(1,Ly);

            % Tamaño salida de bloque yb(n)

            Lyb=Lxb+Lh-1 ; % Lxb es el tamaño del bloque en que se divide la entrada
            
            % Primer bloque
            xb=x(1 : Lxb);
            y(1 : Lyb)=conv(h,xb); %convolución primer bloque
            
            % Señal x(n) parcial
            
            xtemp=xb; 
            
            % Calcular Número de bloques 
            Nb= floor(Lx/Lxb);

            for k=2: Nb
                 
                xb=x(1+(k-1)* Lxb :  Lxb*k ) ;
                yb=conv(h,xb); %convolución bloques restantes
                   
                y( 1+(k-1)*Lxb : (k-1)*Lxb+ Lyb)= y( 1+(k-1)*Lxb : (k-1)*Lxb+ Lyb) + yb;  %Sumar salidas desplazadas
                
                % señal x(n) parcial
                xtemp=[xtemp  xb ];
            
                %Visualizar entrada x(n) por bloques
                 
                stem(app.UIAxes9,xtemp);
                % grid on;
                % xlabel('n'); title('x(n) por bloques'); ylim([-6, 7])
                % 
                % % Visualizar salida y(n) por bloques
                % %plot(app.UIAxes5);
                % stem(app.UIAxes5, y); grid on;
                % xlabel('n'); title('y(n) por bloques'); ylim([-6, 7])

                stem(app.UIAxes9_2,y); 
                xlim(app.UIAxes9_2, [0 length(x)]);
                pause(0.05)
                
            end

            Lxr= mod(Lx,Lxb); 

            % verificar si la longitud de x es múltiplo de Nb
             
            if Lxr ~= 0 % Si hay un bloque incompleto
                
                Lyr=Lxr+Lh-1 ;  % tamaño salida de bloque ybr(n)
                
                xbr=x(1+ Nb*Lxb : Lx) ;
            
                ybr=conv(h,xbr);
                   
                y( 1+Nb*Lxb: Ly)= y( 1+Nb*Lxb: Ly) + ybr;
                
            end 
            % % Visualizar y(n) completa
            % grid on; stem(app.UIAxes5, y); 
            % xlabel('n'); title('y(n) por bloques'); ylim([-6, 7])
            % pause(2)

            res =y;

        end

        function snew = muestrear(app,s,freci,frecf)
            [P,Q] = rat(freci/frecf);
            abs(P/Q*frecf-freci);
            snew =resample(s,P,Q);
            
        end
        
        function Y=ejex(~,Dato,Fs)  %Funció  para definir limites de eje X
            xs=0:1:length(Dato)-1;
            Y=xs.*(1/Fs);
        end

        function filtro = lowpassfilter(app, N, fc, fs)
            f = linspace(-fs/2,fs/2,N);
            filtro = zeros(size(f));
            filtro(abs(f)<=fc) = 1;
        end
        
        function results = ec_diference(app,x,a,b)
 
            N=length(a);     % Orden N 
            M=length(b)-1;   % Orden M
            
            
            % Señal de entrada x(n)
            Lx=length(x);
            
            y=zeros(1,Lx); % Asignar memoria a y(n)
            v=zeros(1,Lx);  % Asignar memoria a v(n)
            
            for  n=0:Lx-1
                
                m=n+1;
                
                % Calcular v(n) 
                temp_v=0;
                
                for k=0:M
                    
                    kk=k+1;
                    ind= n-k+1;
                
                    if  ind >= 1 && ind < Lx-1
                        %n_k= m-kk+1;  % ajuste del indice de x(n-k) para evitar salir rango
                        temp_v=temp_v+b(kk)*x(ind);
                    end
                end
                v(m)=temp_v;
                
                temp_y=0;
                for k=1:N
                   
                    ind= n-k+1;
                
                    if  ind >= 1 && ind < N
                        %n_k= m-kk+1;  % ajuste del indice de x(n-k) para evitar salir rango
                        temp_y=temp_y+a(k)*y(ind);
                    end
            
                end
                y(m)=temp_y+v(m); % Respuesta total
                
            end   
        n=0:Lx-1;
        stem(app.UIAxes9_2,n,y);   
            
        results=y;    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectionFileButton
        function SelectionFileButtonPushed(app, event)
            app.x=app.x+1;

            [filename, pathname] = uigetfile('*.*', 'Seleccione un archivo de audio'); % Seleccionar el archivo de audio
            app.PathEditField.Value = pathname;
            [senal,Fs2] = audioread(fullfile(pathname,filename)); % Leer el archivo de audio

            if app.ModeDropDown.Value == "Mono L"
                app.signals{app.x} = senal(:,1);
            elseif app.ModeDropDown.Value == "Mono R"
                app.signals{app.x} = senal(:,2);
            elseif app.ModeDropDown.Value == "Stereo"
                app.signals{app.x}= senal(:,:);
            end

            app.frec(app.x) = Fs2;
            [ni, nf] = pos(app, 0, app.signals{app.x});
            app.n{app.x} = ni:nf;
            
            name = strcat('File',num2str(app.numarch));
            app.SignalsListBox.Items(end+1) = {name};

            %app.Signal1DropDown.Items(end+1) = {name};
            %app.Signal2DropDown.Items(end+1) = {name};

            app.SignalDropDown.Items(end+1) = {name};

            app.Signal1DropDown_2.Items(end+1) = {name};
            app.Signal2DropDown_2.Items(end+1) = {name};

            app.numarch=app.numarch+1;

            %info = audioinfo(filename) % Información del archivo de audio.
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.x=app.x+1;

            dlg_title = 'Grabar audio - micrófono';
            prompt = { 'Frec. Muestreo:','Bits/muestra:', 'Núm. Canales:'};
            num_lines = 1;
            defaultans = {'44100', '16','1'};
            
            answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
            
            Fs1=str2num(answer{1});    % Frecuencia de muestreo   
            nBit=str2num(answer{2});   % Número de bits por muestra
            nChan=str2num(answer{3});   % Número de canales
            
            
            aud1 = audiorecorder(Fs1,nBit,nChan); % Crear un objeto audiorecorder
            
            % Crear un objeto uiprogressdlg con título e icono
            
            fig = uifigure;
            
            tRec=3; % Tiempo de grabación en s.
            
            d = uiprogressdlg(fig,'Title',['Grabando audio (' num2str(tRec) ' seconds)'], 'Icon', 'C:\Users\Miller Ortiz\Univalle\DSP\Proyecto1\70_Rojo.jpg');
            
            %disp('Iniciando la grabación...');
            
            d.Message = sprintf('Hable ahora!! %.1f segundos', 0.1);
            
            recordblocking(aud1, tRec); % Grabar  audio
            
            % Actualizar la barra de avance y el mensaje cada 0.1 segundos
            delta=0.1;
            for i = 0.2:delta:tRec-delta
                d.Value = i/tRec;
                d.Message = sprintf('Tiempo transcurrido: %.1f segundos', i);
                pause(0.1);
            end
            % Cerrar el diálogo de progreso
            
            close(d);
            close(fig)
            
            %disp('Fin de la grabación.');
            x1 = getaudiodata(aud1); % Obtener los datos de la señal
            app.signals{app.x} = x1;
            app.frec(app.x) = Fs1;
            [ni, nf] = pos(app, 0, app.signals{app.x});
            app.n{app.x} = ni:nf;

            audiowrite('C:\Users\Miller Ortiz\Univalle\DSP\Proyecto1\GrabacionMicro.wav',x1,Fs1); % Guardar los datos en un archivo WAV
            
            name = strcat('Record',num2str(app.numgrab));
            app.SignalsListBox.Items(end+1) = {name};

            %app.Signal1DropDown.Items(end+1) = {name};
            %app.Signal2DropDown.Items(end+1) = {name};

            app.SignalDropDown.Items(end+1) = {name};

            app.Signal1DropDown_2.Items(end+1) = {name};
            app.Signal2DropDown_2.Items(end+1) = {name};

            app.numgrab=app.numgrab+1;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if app.selec ~= 0
                app.player = audioplayer(app.signals{app.selec},app.frec(app.selec));
                play(app.player);
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.x=app.x+1;
            
            opc = app.TypeofsignalDropDown.Value;

            Fs3= app.SamplingFrequencyEditField.Value;
            time = app.TimesSpinner.Value;
            amplitude = app.AmplitudeSpinner.Value;
            frequency = app.InitialFrequencyHzSpinner.Value;
            finalfrequency = app.FinalFrequencyHzSpinner.Value;
            phase = app.PhaseSpinner.Value;
            tiempo= 0:1/Fs3:time;

            if opc=="Sine"
                x3 = amplitude*sin((2*pi*frequency*tiempo)+(phase*pi/180));
            elseif opc=="Cosine"
                x3 = amplitude*cos((2*pi*frequency*tiempo)+(phase*pi/180));
            elseif opc=="Square"
                x3 = amplitude*sign(sin(2*pi*frequency*tiempo));
            elseif opc=="Sawtooth"    
                x3 = amplitude*(2 *(tiempo/(1/frequency)-floor(tiempo/(1/frequency)+ 1/2)));
            elseif opc=="Pulses"
                x3 = (mod(floor(tiempo/0.1),10)<1)*amplitude;
            elseif opc== "Chirp"
                fase_actual = 2*pi*tiempo .* ((finalfrequency - frequency) / 2 * tiempo + frequency);
                x3 = amplitude*sin(fase_actual);
            end

            app.signals{app.x} = transpose(x3);
            app.frec(app.x) = Fs3;
            [ni, nf] = pos(app, 0, app.signals{app.x});
            app.n{app.x} = ni:nf;

            name = strcat('Sinte',num2str(app.numsinte));
            app.SignalsListBox.Items(end+1) = {name};

            %app.Signal1DropDown.Items(end+1) = {name};
            %app.Signal2DropDown.Items(end+1) = {name};
            
            app.SignalDropDown.Items(end+1) = {name};

            app.Signal1DropDown_2.Items(end+1) = {name};
            app.Signal2DropDown_2.Items(end+1) = {name};
            
            app.numsinte=app.numsinte+1;

        end

        % Clicked callback: SignalsListBox
        function SignalsListBoxClicked(app, event)
            app.selec = event.InteractionInformation.Item;
            
            plot(app.UIAxes,app.signals{app.selec});
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player);
        end

        % Callback function
        function Signal1DropDownClicked(app, event)
            app.s1 = event.InteractionInformation.Item;
            if app.s1 ~= 0
                app.proce{1} = app.signals{app.s1};
                app.frecproce(1) = app.frec(app.s1);
                plot(app.UIAxes2,app.proce{1});
                app.UITable.Data(1,1) = length(app.proce{1});
                app.UITable.Data(1,2) = app.frecproce(1);
                app.UITable.Data(2,1) = 0;
                app.UITable.Data(2,2) = 0;
            end
        end

        % Callback function
        function Signal2DropDownClicked(app, event)
            app.s2 = event.InteractionInformation.Item;
            if app.s2 ~= 0
                app.proce{2} = app.signals{app.s2};
                app.frecproce(2) = app.frec(app.s2);
                plot(app.UIAxes3,app.proce{2});
                app.UITable2.Data(1,1) = length(app.proce{2});
                app.UITable2.Data(1,2) = app.frecproce(2);
                app.UITable2.Data(2,1) = 0;
                app.UITable2.Data(2,2) = 0;
            end
            
        end

        % Callback function
        function NormalizationButtonPushed(app, event)
            s1n = normalizar(app, app.proce{1});
            app.proce{1} = s1n;

            plot(app.UIAxes2,app.proce{1});

            s2n = normalizar(app, app.proce{2});
            app.proce{2} = s2n;

            plot(app.UIAxes3,app.proce{2});
        end

        % Callback function
        function DimentionsButtonPushed(app, event)
            ls1 = length(app.proce{1});
            ls2 = length(app.proce{2});

            pad = abs(ls1-ls2);

            if ls1 > ls2
                app.proce{2}(ls2+1:ls2+pad) = 0;
            end

            if ls1 < ls2
                app.proce{1}(ls1+1:ls1+pad) = 0;
            end

            plot(app.UIAxes2,app.proce{1});
            app.UITable.Data(2,1) = length(app.proce{1});
            app.UITable.Data(2,2) = app.frecproce(1);

            plot(app.UIAxes3,app.proce{2});
            app.UITable2.Data(2,1) = length(app.proce{2});
            app.UITable2.Data(2,2) = app.frecproce(2);

                
        end

        % Clicked callback: SignalDropDown
        function SignalDropDownClicked(app, event)
            app.sfil = event.InteractionInformation.Item;
            if app.sfil ~= 0
                app.proce{1} = app.signals{app.sfil};
                app.frecproce(1) = app.frec(app.sfil);
                app.nproce{1} = app.n{app.sfil};
                app.FrequencySignalEditField.Value = app.frecproce(1);
                app.SizeSignalEditField.Value = length(app.proce{1});
                plot(app.UIAxes4,app.nproce{1},app.proce{1});
            end
        end

        % Callback function
        function NormalizationButton_2Pushed(app, event)
            sfn = normalizar(app, app.proce{1});
            app.proce{1} = sfn;

            plot(app.UIAxes4,app.proce{1});
        end

        % Clicked callback: Signal1DropDown_2
        function Signal1DropDown_2Clicked(app, event)
            app.signal1 = event.InteractionInformation.Item;
            if app.signal1 ~= 0
                app.proce{1} = app.signals{app.signal1};
                app.nproce{1} = app.n{app.signal1};
                app.frecproce(1) = app.frec(app.signal1);
                plot(app.UIAxes6,app.nproce{1},app.proce{1});
                app.UITable3.Data(1,1) = length(app.proce{1});
                app.UITable3.Data(1,2) = app.frecproce(1);
            end
        end

        % Clicked callback: Signal2DropDown_2
        function Signal2DropDown_2Clicked(app, event)
            app.signal2 = event.InteractionInformation.Item;
            if app.signal2 ~= 0
                app.proce{2} = app.signals{app.signal2};
                app.nproce{2} = app.n{app.signal2};
                app.frecproce(2) = app.frec(app.signal2);
                plot(app.UIAxes7,app.nproce{2},app.proce{2});
                app.UITable4.Data(1,1) = length(app.proce{2});
                app.UITable4.Data(1,2) = app.frecproce(2);
            end
        end

        % Button pushed function: AdvanceButton
        function AdvanceButtonPushed(app, event)
            op = "adelanto";
            k = app.TimeEditField.Value;

            if app.SourceDropDown.Value == "Signal 1"
                nd = desplazar(app, k, app.nproce{1}(1), app.nproce{1}(end), op);
                app.nproce{1} = nd;
                plot(app.UIAxes6,app.nproce{1},app.proce{1});
            elseif app.SourceDropDown.Value == "Signal 2"
                nd = desplazar(app, k, app.nproce{2}(1), app.nproce{2}(end), op);
                app.nproce{2} = nd;
                plot(app.UIAxes7,app.nproce{2},app.proce{2});
            end

        end

        % Button pushed function: DelayButton
        function DelayButtonPushed(app, event)
            op = "retraso";
            k = app.TimeEditField.Value;

            if app.SourceDropDown.Value == "Signal 1"
                nd = desplazar(app, k, app.nproce{1}(1), app.nproce{1}(end), op);
                app.nproce{1} = nd;
                plot(app.UIAxes6,app.nproce{1},app.proce{1});
            elseif app.SourceDropDown.Value == "Signal 2"
                nd = desplazar(app, k, app.nproce{2}(1), app.nproce{2}(end), op);
                app.nproce{2} = nd;
                plot(app.UIAxes7,app.nproce{2},app.proce{2});
            end

            
        end

        % Button pushed function: AddSignalsButton
        function AddSignalsButtonPushed(app, event)
            app.proce{3} = app.proce{1} + app.proce{2};
            plot(app.UIAxes8,app.proce{3});
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            ls1 = length(app.proce{1});
            ls2 = length(app.proce{2});

            pad = abs(ls1-ls2);

            if ls1 > ls2
                app.proce{2}(ls2+1:ls2+pad,:) = 0;
                [ni, nf] = pos(app,app.nproce{2}(1),app.proce{2});
                app.nproce{2} = ni:nf;
            end

            if ls1 < ls2
                app.proce{1}(ls1+1:ls1+pad,:) = 0;
                [ni, nf] = pos(app,app.nproce{1}(1),app.proce{1});
                app.nproce{1} = ni:nf;
            end
            
            app.UITable3.Data(1,1) = length(app.proce{1});
            app.UITable3.Data(1,2) = app.frecproce(1);
            app.UITable4.Data(1,1) = length(app.proce{2});
            app.UITable4.Data(1,2) = app.frecproce(2);
            plot(app.UIAxes6,app.nproce{1},app.proce{1});
            plot(app.UIAxes7,app.nproce{2},app.proce{2});

        end

        % Button pushed function: MultiplySignalsButton
        function MultiplySignalsButtonPushed(app, event)
            app.proce{3} = app.proce{1}.*app.proce{2};
            plot(app.UIAxes8,app.proce{3});
        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            
            if app.SourceDropDown.Value == "Signal 1"
                s = normalizar(app, app.proce{1});
                app.proce{1} = s;
                plot(app.UIAxes6,app.nproce{1},app.proce{1});
                app.UITable3.Data(1,1) = length(app.proce{1});
                app.UITable3.Data(1,2) = app.frecproce(1);
          
            elseif app.SourceDropDown.Value == "Signal 2"
                s = normalizar(app, app.proce{2});
                app.proce{2} = s;
                plot(app.UIAxes7,app.nproce{2},app.proce{2});
                app.UITable4.Data(1,1) = length(app.proce{2});
                app.UITable4.Data(1,2) = app.frecproce(2);
            end

        end

        % Button pushed function: PlayButton_3
        function PlayButton_3Pushed(app, event)
            if app.SourceDropDown.Value == "Signal 1"
                app.player = audioplayer(app.proce{1},app.frecproce(1));
                play(app.player);
            elseif app.SourceDropDown.Value == "Signal 2"
                app.player = audioplayer(app.proce{2},app.frecproce(2));
                play(app.player);
            end
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            stop(app.player);
        end

        % Button pushed function: PlayOutputButton
        function PlayOutputButtonPushed(app, event)
            app.player = audioplayer(app.proce{3}, app.UITable3.Data(1,2));
            play(app.player);
        end

        % Button pushed function: ReversePlayButton
        function ReversePlayButtonPushed(app, event)
            if app.SourceDropDown.Value == "Signal 1"
                s = app.proce{1};
                f = app.frecproce(1);
            elseif app.SourceDropDown.Value == "Signal 2"
                s = app.proce{2};
                f = app.frecproce(2);
            end

            y = flip(s);
            app.player = audioplayer(y,f);
            play(app.player);
        end

        % Value changing function: ConstantKnob
        function ConstantKnobValueChanging(app, event)
            changingValue = event.Value;
            valor = changingValue/50;
            if app.SourceDropDown.Value == "Signal 1"
                s = valor * app.signals{app.signal1};
                app.proce{1} = s;
                plot(app.UIAxes6,app.nproce{1},app.proce{1});
            elseif app.SourceDropDown.Value == "Signal 2"
                s = valor * app.signals{app.signal2};
                app.proce{2} = s;
                plot(app.UIAxes7,app.nproce{2},app.proce{2});
            end

        end

        % Button pushed function: AdjustVolumeLinearlyButton
        function AdjustVolumeLinearlyButtonPushed(app, event)
            if app.SourceDropDown.Value == "Signal 1"
                if app.LinearSwitch.Value == "Up"
                    v=1;
                    for i = 0:(1/(length(app.signals{app.signal1})-1)):1
                    app.proce{1}(v,1) = i*app.signals{app.signal1}(v,1);
                    v=v+1;
                    end
                    plot(app.UIAxes6,app.nproce{1},app.proce{1});
                elseif app.LinearSwitch.Value == "Down"
                    v=1;
                    for i = 1:-(1/(length(app.signals{app.signal1})-1)):0
                    app.proce{1}(v,1) = i*app.signals{app.signal1}(v,1);
                    v=v+1;
                    end
                    plot(app.UIAxes6,app.nproce{1},app.proce{1});
                end
            elseif app.SourceDropDown.Value == "Signal 2"
                if app.LinearSwitch.Value == "Up"
                    v=1;
                    for i = 0:(1/(length(app.signals{app.signal2})-1)):1
                    app.proce{2}(v,1) = i*app.signals{app.signal2}(v,1);
                    v=v+1;
                    end
                    plot(app.UIAxes7,app.nproce{2},app.proce{2});
                elseif app.LinearSwitch.Value == "Down"
                    v=1;
                    for i = 1:-(1/(length(app.signals{app.signal2})-1)):0
                    app.proce{2}(v,1) = i*app.signals{app.signal2}(v,1);
                    v=v+1;
                    end
                    plot(app.UIAxes7,app.nproce{2},app.proce{2});
                end
            end


        end

        % Button pushed function: ResamplingButton
        function ResamplingButtonPushed(app, event)
            newfrec = app.FrecuencyEditField.Value;

            if app.SourceDropDown.Value == "Signal 1"
                snew = muestrear(app,app.proce{1},app.frecproce(1),newfrec);
                app.proce{1} = snew;
                app.frecproce(1) = newfrec;
                [ni,nf] = pos(app,app.nproce{1}(1),snew);
                app.nproce{1} = ni:nf;
                plot(app.UIAxes6,app.nproce{1},app.proce{1});
                app.UITable3.Data(1,1) = length(app.proce{1});
                app.UITable3.Data(1,2) = app.frecproce(1);
            elseif app.SourceDropDown.Value == "Signal 2"
                snew = muestrear(app,app.proce{2},app.frecproce(2),newfrec);
                app.proce{2} = snew;
                app.frecproce(2) = newfrec;
                [ni,nf] = pos(app,app.nproce{2}(1),snew);
                app.nproce{2} = ni:nf;
                plot(app.UIAxes7,app.nproce{2},app.proce{2});
                app.UITable4.Data(1,1) = length(app.proce{2});
                app.UITable4.Data(1,2) = app.frecproce(2);
            end

        end

        % Button pushed function: GenerateButton_2
        function GenerateButton_2Pushed(app, event)
            if app.SourceDropDown.Value == "Signal 1"
                app.proce{3} = app.proce{1}(:,1)+app.proce{1}(:,2);
                plot(app.UIAxes8,app.proce{3});
            elseif app.SourceDropDown.Value == "Signal 2"
                app.proce{3} = app.proce{2}(:,1)+app.proce{2}(:,2);
                plot(app.UIAxes8,app.proce{3});
            end
        end

        % Button pushed function: SaveOutputButton
        function SaveOutputButtonPushed(app, event)
            app.x=app.x+1;
            app.signals{app.x} = app.proce{3};
            app.frec(app.x) = app.UITable3.Data(1,2);

            [ni, nf] = pos(app, 0, app.signals{app.x});
            app.n{app.x} = ni:nf;
            
            name = strcat('Gener',num2str(app.numgener));
            app.SignalsListBox.Items(end+1) = {name};

            app.SignalDropDown.Items(end+1) = {name};

            app.Signal1DropDown_2.Items(end+1) = {name};
            app.Signal2DropDown_2.Items(end+1) = {name};

            app.numgener=app.numgener+1;
        end

        % Button pushed function: GenerateButton_4
        function GenerateButton_4Pushed(app, event)
            app.proce{3}(:,1) = app.proce{1};
            app.proce{3}(:,2) = app.proce{2};

            plot(app.UIAxes8,app.proce{3});
        end

        % Button pushed function: GenerateButton_3
        function GenerateButton_3Pushed(app, event)
            app.proce{3}(:,1) = app.proce{1};
            app.proce{3}(:,2) = app.proce{1}(end:-1:1);

            plot(app.UIAxes8,app.proce{3});
        end

        % Button pushed function: NANButton
        function NANButtonPushed(app, event)
            if app.SourceDropDown.Value == "Signal 1"
                cond = isnan(app.proce{1});
                if ~isempty(find(cond,1))
                    app.EditField.Value = "NAN data exists";
                else
                    app.EditField.Value = "No NAN data exists";
                end
            elseif app.SourceDropDown.Value == "Signal 2"
                cond = isnan(app.proce{2});
                if ~isempty(find(cond,1))
                    app.EditField.Value = "NAN data exists";
                else
                    app.EditField.Value = "No NAN data exists";
                end
            end
        end

        % Button pushed function: INFButton
        function INFButtonPushed(app, event)
            if app.SourceDropDown.Value == "Signal 1"
                cond = isinf(app.proce{1});
                if ~isempty(find(cond,1))
                    app.EditField.Value = "INF data exists";
                else
                    app.EditField.Value = "No INF data exists";
                end
            elseif app.SourceDropDown.Value == "Signal 2"
                cond = isinf(app.proce{2});
                if ~isempty(find(cond,1))
                    app.EditField.Value = "INF data exists";
                else
                    app.EditField.Value = "No INF data exists";
                end
            end
        end

        % Button pushed function: EliminateButton
        function EliminateButtonPushed(app, event)
            if app.SourceDropDown.Value == "Signal 1"
                for i = 1:length(app.proce{1})
                    if app.proce{1}(i,:) == "NaN"
                        app.proce{1}(i,:) = 0;
                    elseif app.proce{1}(i,:) == "Inf"
                        app.proce{1}(i,:) = 0;
                    end
                end
            elseif app.SourceDropDown.Value == "Signal 2"
                for i = 1:length(app.proce{2})
                    if app.proce{2}(i,:) == "NaN"
                        app.proce{2}(i,:) = 0;
                    elseif app.proce{2}(i,:) == "Inf"
                        app.proce{2}(i,:) = 0;
                    end
                end
            end
        end

        % Button pushed function: FourierTransformButton
        function FourierTransformButtonPushed(app, event)
            app.fourier{1} = fft(app.proce{1});
            f1 = linspace(0,app.frecproce(1),length(app.fourier{1}));
            plot(app.UIAxes5, f1, abs(app.fourier{1}));

            F = app.frecproce(1);
            T = 1/F;
            t = app.nproce{1}*T;
            cr = app.proce{1} + transpose(2*randn(size(t)));
            app.proce{2} = cr;
            app.frecproce(2) = app.frecproce(1);
            app.fourier{2} = fft(cr);
            f2 = linspace(0,F,length(app.fourier{2}));
            plot(app.UIAxes10, f2, abs(app.fourier{2}));


        end

        % Callback function
        function OptionsDropDownValueChanged(app, event)
            value = app.OptionsDropDown.Value;

            if value == "With Noise"
                F = app.frecproce(1);
                T = 1/F;
                t = app.nproce{1}*T;

                cr = app.proce{1} + transpose(2*randn(size(t)));
                app.proce{1} = cr;

            elseif value == "Without Noise"
                app.proce{1} = app.signals{app.sfil};
            end

            plot(app.UIAxes4,app.nproce{1},app.proce{1});
        end

        % Button pushed function: GenerateButton_5
        function GenerateButton_5Pushed(app, event)

            ventana = app.SizeWindowEditField.Value;
            if app.FilterTypeDropDown.Value == "FIR 1"
                h= transpose (readmatrix('Filtros_Prueba_Proyecto1_DSP_Entregable.xlsx', 'Sheet','Filtro FIR 1','Range','B6:B31'));
                %h = [-0.000779024944408449136062921880352405424 0.000999731876142587964212404827435420884 0.003831402877460619166294364390523696784 0.006331215011077922713567112111832102528 0.004203293161063792818255002714522561291 -0.006457016133813054704904121905428837636 -0.023107528738289289521601332921818539035 -0.033457559743661630102806725517439190298 -0.019904587044922469885044336024293443188 0.028977468595656773187041466144364676438 0.107219132728444313529259090955747524276 0.189581211105815133910468262001813855022 0.242562261249433724907831333439389709383 0.242562261249433724907831333439389709383 0.189581211105815133910468262001813855022 0.107219132728444313529259090955747524276 0.028977468595656773187041466144364676438 -0.019904587044922469885044336024293443188 -0.033457559743661630102806725517439190298 -0.023107528738289289521601332921818539035 -0.006457016133813054704904121905428837636 0.004203293161063792818255002714522561291 0.006331215011077922713567112111832102528 0.003831402877460619166294364390523696784 0.000999731876142587964212404827435420884 -0.000779024944408449136062921880352405424];
                app.proce{2} = h;
                [nih, nfh] = pos(app, 0, app.proce{2});
                app.nproce{2} = nih:nfh;
    
                app.proce{3} = convo(app, transpose(app.proce{1}), app.nproce{1}, app.proce{2}, app.nproce{2}, ventana);
                app.frecproce(3) = app.frecproce(1);

                [nih, nfh] = pos(app, 0, app.proce{3});
                app.nproce{3} = nih:nfh;

            elseif app.FilterTypeDropDown.Value == "FIR 2"
                h= transpose (readmatrix('Filtros_Prueba_Proyecto1_DSP_Entregable.xlsx', 'Sheet','Filtro FIR 2','Range','B6:B31'));
                app.proce{2} = h;
                app.proce{2} = h;
                [nih, nfh] = pos(app, 0, app.proce{2});
                app.nproce{2} = nih:nfh;
    
                app.proce{3} = convo(app, transpose(app.proce{1}), app.nproce{1}, app.proce{2}, app.nproce{2}, ventana);
                app.frecproce(3) = app.frecproce(1);

                [nih, nfh] = pos(app, 0, app.proce{3});
                app.nproce{3} = nih:nfh;

            elseif app.FilterTypeDropDown.Value == "FIR 3"
                h= transpose (readmatrix('Filtros_Prueba_Proyecto1_DSP_Entregable.xlsx', 'Sheet','Filtro FIR 3','Range','B6:B46'));
                app.proce{2} = h;
                app.proce{2} = h;
                [nih, nfh] = pos(app, 0, app.proce{2});
                app.nproce{2} = nih:nfh;
    
                app.proce{3} = convo(app, transpose(app.proce{1}), app.nproce{1}, app.proce{2}, app.nproce{2}, ventana);
                app.frecproce(3) = app.frecproce(1);

                [nih, nfh] = pos(app, 0, app.proce{3});
                app.nproce{3} = nih:nfh;

            elseif app.FilterTypeDropDown.Value == "FIR 4"
                h= transpose (readmatrix('Filtros_Prueba_Proyecto1_DSP_Entregable.xlsx', 'Sheet','Filtro FIR 4','Range','B6:B46'));
                app.proce{2} = h;
                app.proce{2} = h;
                [nih, nfh] = pos(app, 0, app.proce{2});
                app.nproce{2} = nih:nfh;
    
                app.proce{3} = convo(app, transpose(app.proce{1}), app.nproce{1}, app.proce{2}, app.nproce{2}, ventana);
                app.frecproce(3) = app.frecproce(1);

                [nih, nfh] = pos(app, 0, app.proce{3});
                app.nproce{3} = nih:nfh;

            elseif app.FilterTypeDropDown.Value == "FIR hn"
                [nih, nfh] = pos(app, 0, app.proce{2});
                app.nproce{2} = nih:nfh;
    
                app.proce{3} = convo(app, transpose(app.proce{1}), app.nproce{1}, app.proce{2}, app.nproce{2}, ventana);
                app.frecproce(3) = app.frecproce(1);

                [nih, nfh] = pos(app, 0, app.proce{3});
                app.nproce{3} = nih:nfh;
                
            elseif app.FilterTypeDropDown.Value == "IIR 1"
                b = transpose (readmatrix('Filtros_Prueba_Proyecto1_DSP_Entregable.xlsx', 'Sheet','Filtro IIR 1','Range','B6:B11'));
                a = transpose (readmatrix('Filtros_Prueba_Proyecto1_DSP_Entregable.xlsx', 'Sheet','Filtro IIR 1','Range','B13:B18'));
                app.proce{3}= ec_diference(app,app.proce{1}, a, b);
                app.frecproce(3) = app.frecproce(1);

                [nih, nfh] = pos(app, 0, app.proce{3});
                app.nproce{3} = nih:nfh;

            elseif app.FilterTypeDropDown.Value == "IIR 2"
                b = transpose (readmatrix('Filtros_Prueba_Proyecto1_DSP_Entregable.xlsx', 'Sheet','Filtro IIR 2','Range','B6:B11'));
                a = transpose (readmatrix('Filtros_Prueba_Proyecto1_DSP_Entregable.xlsx', 'Sheet','Filtro IIR 2','Range','B13:B18'));
                app.proce{3}= ec_diference(app,app.proce{1}, a, b);
                app.frecproce(3) = app.frecproce(1);

                [nih, nfh] = pos(app, 0, app.proce{3});
                app.nproce{3} = nih:nfh;

            elseif app.FilterTypeDropDown.Value == "IIR 3"
                b = transpose (readmatrix('Filtros_Prueba_Proyecto1_DSP_Entregable.xlsx', 'Sheet','Filtro IIR 3','Range','B7:B13'));
                a = transpose (readmatrix('Filtros_Prueba_Proyecto1_DSP_Entregable.xlsx', 'Sheet','Filtro IIR 3','Range','B15:B21'));
                app.proce{3}= ec_diference(app,app.proce{1}, a, b);
                app.frecproce(3) = app.frecproce(1);

                [nih, nfh] = pos(app, 0, app.proce{3});
                app.nproce{3} = nih:nfh;

            elseif app.FilterTypeDropDown.Value == "IIR 4"
                b = transpose (readmatrix('Filtros_Prueba_Proyecto1_DSP_Entregable.xlsx', 'Sheet','Filtro IIR 4','Range','B6:B12'));
                a = transpose (readmatrix('Filtros_Prueba_Proyecto1_DSP_Entregable.xlsx', 'Sheet','Filtro IIR 4','Range','B14:B20'));
                app.proce{3}= ec_diference(app,app.proce{1}, a, b);
                app.frecproce(3) = app.frecproce(1);

                [nih, nfh] = pos(app, 0, app.proce{3});
                app.nproce{3} = nih:nfh;

            elseif app.FilterTypeDropDown.Value == "IIR k"
                app.proce{3}= ec_diference(app,app.proce{1}, app.akbk{1}, app.akbk{2});
                app.frecproce(3) = app.frecproce(1);

                [nih, nfh] = pos(app, 0, app.proce{3});
                app.nproce{3} = nih:nfh;
            end


        end

        % Button pushed function: PlaySignalButton
        function PlaySignalButtonPushed(app, event)
            app.player = audioplayer(app.proce{1},app.frecproce(1));
            play(app.player);
        end

        % Button pushed function: PlayOutputButton_2
        function PlayOutputButton_2Pushed(app, event)
            app.player = audioplayer(app.proce{3},app.frecproce(3));
            play(app.player);
        end

        % Button pushed function: StopSignalButton
        function StopSignalButtonPushed(app, event)
            stop(app.player);
        end

        % Button pushed function: StopOutputButton
        function StopOutputButtonPushed(app, event)
            stop(app.player);
        end

        % Button pushed function: GeneratefromDACButton
        function GeneratefromDACButtonPushed(app, event)
            app.x = app.x+1;
            % Crear un objeto de sesión DAQ para la tarjeta NI 6009
            s = daq.createSession('ni');
            
            % Agregar un canal analógico de entrada (AI) a la sesión
            addAnalogInputChannel(s, 'Dev1', "ai1", 'Voltage');
            
            % Configurar la frecuencia de muestreo y la duración de la adquisición
            s.Rate = 1000; % Frecuencia de muestreo en muestras por segundo
            s.DurationInSeconds = app.TimeEditField_2.Value; % Duración de la adquisición en segundos
            
            % Iniciar la adquisición de datos
            [data, times] = startForeground(s);
            app.signals{app.x} = data;
            app.frec(app.x) = s.Rate;
            [ni, nf] = pos(app, 0, app.signals{app.x});
            app.n{app.x} = ni:nf;

            name = strcat('ADQ',num2str(app.numADQ));
            app.SignalsListBox.Items(end+1) = {name};

            app.SignalDropDown.Items(end+1) = {name};

            app.Signal1DropDown_2.Items(end+1) = {name};
            app.Signal2DropDown_2.Items(end+1) = {name};

            app.numADQ=app.numADQ+1;

        end

        % Button pushed function: FIRhnButton
        function FIRhnButtonPushed(app, event)
            res = inputdlg('Enter the values ��h(n) separated by space: ', 'Filter FIR - h(n)', [1 50]);
            valor = str2num(res{:});
            h = transpose(valor);
            app.proce{2} = h;
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            value = app.FilterTypeDropDown.Value;
            if value == "FIR hn"
                app.FIRhnButton.Enable= "on";
            elseif value == "IIR k"
                app.IIRakbkButton.Enable = "on";
            else
                app.FIRhnButton.Enable= "off";
                app.IIRakbkButton.Enable = "off";
            end
        end

        % Callback function: not associated with a component
        function FilteredSignalButtonPushed(app, event)
            fc = 20;
            factor_pad = 2;
            
            % Corregir la función 'length' a 'length'
            senal = [app.fourier{1}, zeros(1, factor_pad * length(app.fourier{1})-1)];
            
            fs = app.frecproce(1);
            
            % Asumiendo que lowpassfilter es una función que devuelve un filtro
            filtrotemp = lowpassfilter(app, length(senal), fc, fs);
            
            senal_pad = conv(senal, filtrotemp, 'same');
            senal_eli_pad = senal_pad(1:length(app.fourier{1}));
            
            plot(app.UIAxes11, app.nproce{1}, real(senal_eli_pad));


        end

        % Button pushed function: IIRakbkButton
        function IIRakbkButtonPushed(app, event)
            respuesta_ab = inputdlg({'Ingresar los valores ak separados por espacio: ','Ingresar los valores bk separados por espacio: '}, 'Filter IIR - ak & bk', [1 50; 1 50]);
            valor_usuarioak = str2num(respuesta_ab{1}); 
            valor_usuariobk = str2num(respuesta_ab{2}); 
            valor_usuarioak = valor_usuarioak.';
            valor_usuariobk = valor_usuariobk.';
            app.akbk{1} = valor_usuarioak;
            app.akbk{2} = valor_usuariobk;
        end

        % Button pushed function: SpectrumOUTButton
        function SpectrumOUTButtonPushed(app, event)
            window = hann(256); % Ventana de Hann con longitud 256
            overlap = 128; % Superposición entre ventanas
            nfft = 1024; % Número de puntos de la FFT
            
            % Calcular el espectrograma
            [s, f, t] = spectrogram(app.proce{3}, window, overlap, nfft, app.frecproce(3));
            
            % Mostrar el espectrograma
            imagesc(app.UIAxes11,t, f, 10*log10(abs(s)));
            axis (app.UIAxes11,'xy'); % Invierte el eje y para que las frecuencias bajas estén en la parte inferior
            title(app.UIAxes11,'Output spectrogram');
            xlabel(app.UIAxes11,'Time (s)');
            ylabel(app.UIAxes11,['Frequency (Hz)']);
            colorbar(app.UIAxes11); % Agrega la barra de colores para indicar la amplitud

            
        end

        % Button pushed function: SpectumINButton
        function SpectumINButtonPushed(app, event)
            window = hann(256); % Ventana de Hann con longitud 256
            overlap = 128; % Superposición entre ventanas
            nfft = 1024; % Número de puntos de la FFT
            
            % Calcular el espectrograma
            [s, f, t] = spectrogram(app.proce{1}, window, overlap, nfft, app.frecproce(1));
            
            % Mostrar el espectrograma
            imagesc(app.UIAxes5_2,t, f, 10*log10(abs(s)));
            axis (app.UIAxes5_2,'xy'); % Invierte el eje y para que las frecuencias bajas estén en la parte inferior
            title(app.UIAxes5_2,'Input spectrogram');
            xlabel(app.UIAxes5_2,'Time (s)');
            ylabel(app.UIAxes5_2,['Frequency (Hz)']);
            colorbar(app.UIAxes5_2); % Agrega la barra de colores para indicar la amplitud


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.UIAxes = uiaxes(app.AcquisitionTab);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.DataAcquisitionLabel = uilabel(app.AcquisitionTab);
            app.DataAcquisitionLabel.Text = 'Data Acquisition:';
            app.RecordLabel = uilabel(app.AcquisitionTab);
            app.RecordLabel.Text = 'Record:';
            app.ImportFileLabel = uilabel(app.AcquisitionTab);
            app.ImportFileLabel.Text = 'Import File:';
            app.DACNationalInstrumentLabel = uilabel(app.AcquisitionTab);
            app.DACNationalInstrumentLabel.Text = 'DAC National Instrument:';
            app.RecordButton = uibutton(app.AcquisitionTab, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Icon = fullfile(pathToMLAPP, 're.png');
            app.RecordButton.Text = 'Record';
            app.SelectionFileButton = uibutton(app.AcquisitionTab, 'push');
            app.SelectionFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectionFileButtonPushed, true);
            app.SelectionFileButton.Text = 'Selection File';
            app.PlayButton = uibutton(app.AcquisitionTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'P.png');
            app.PlayButton.Text = 'Play';
            app.GenerateButton = uibutton(app.AcquisitionTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.StopButton = uibutton(app.AcquisitionTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'st.png');
            app.StopButton.Text = 'Stop';
            app.TypeofsignalDropDownLabel = uilabel(app.AcquisitionTab);
            app.TypeofsignalDropDownLabel.Text = 'Type of signal';
            app.TypeofsignalDropDown = uidropdown(app.AcquisitionTab);
            app.TypeofsignalDropDown.Items = {'Sine', 'Cosine', 'Square', 'Sawtooth', 'Pulses', 'Chirp'};
            app.TypeofsignalDropDown.Value = 'Sine';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.AcquisitionTab);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.AcquisitionTab, 'numeric');
            app.SamplingFrequencyEditField.Value = 3000;
            app.TimesSpinnerLabel = uilabel(app.AcquisitionTab);
            app.TimesSpinnerLabel.Text = 'Time [s]';
            app.TimesSpinner = uispinner(app.AcquisitionTab);
            app.TimesSpinner.Value = 5;
            app.AmplitudeSpinnerLabel = uilabel(app.AcquisitionTab);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude';
            app.AmplitudeSpinner = uispinner(app.AcquisitionTab);
            app.AmplitudeSpinner.Value = 2;
            app.InitialFrequencyHzSpinnerLabel = uilabel(app.AcquisitionTab);
            app.InitialFrequencyHzSpinnerLabel.Text = 'Initial Frequency [Hz]';
            app.InitialFrequencyHzSpinner = uispinner(app.AcquisitionTab);
            app.InitialFrequencyHzSpinner.Value = 5;
            app.FinalFrequencyHzSpinnerLabel = uilabel(app.AcquisitionTab);
            app.FinalFrequencyHzSpinnerLabel.Text = 'Final Frequency [Hz]';
            app.FinalFrequencyHzSpinner = uispinner(app.AcquisitionTab);
            app.FinalFrequencyHzSpinner.Value = 750;
            app.PhaseSpinnerLabel = uilabel(app.AcquisitionTab);
            app.PhaseSpinnerLabel.Text = 'Phase [°]';
            app.PhaseSpinner = uispinner(app.AcquisitionTab);
            app.PhaseSpinner.Value = 20;
            app.SignalsListBoxLabel = uilabel(app.AcquisitionTab);
            app.SignalsListBoxLabel.Text = 'Signals';
            app.SignalsListBox = uilistbox(app.AcquisitionTab);
            app.SignalsListBox.Items = {};
            app.SignalsListBox.ClickedFcn = createCallbackFcn(app, @SignalsListBoxClicked, true);
            app.SignalsListBox.Value = {};
            app.PathLabel = uilabel(app.AcquisitionTab);
            app.PathLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.AcquisitionTab, 'text');
            app.Label = uilabel(app.AcquisitionTab);
            app.Label.Text = '__________________________________________________________________________________________________________________';
            app.ModeDropDownLabel = uilabel(app.AcquisitionTab);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.AcquisitionTab);
            app.ModeDropDown.Items = {'Mono L', 'Mono R', 'Stereo'};
            app.ModeDropDown.Value = 'Mono L';
            app.SinteticsLabel = uilabel(app.AcquisitionTab);
            app.SinteticsLabel.Text = 'Sintetics:';
            app.GeneratefromDACButton = uibutton(app.AcquisitionTab, 'push');
            app.GeneratefromDACButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratefromDACButtonPushed, true);
            app.GeneratefromDACButton.Text = 'Generate from DAC';
            app.TimeEditField_2Label = uilabel(app.AcquisitionTab);
            app.TimeEditField_2Label.Text = 'Time';
            app.TimeEditField_2 = uieditfield(app.AcquisitionTab, 'numeric');
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.UIAxes4 = uiaxes(app.FilteringTab);
            title(app.UIAxes4, 'Input Signal')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes9 = uiaxes(app.FilteringTab);
            title(app.UIAxes9, 'X(n)  by blocks')
            xlabel(app.UIAxes9, 'n')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes9_2 = uiaxes(app.FilteringTab);
            title(app.UIAxes9_2, 'Y(n)  by blocks / Filtered Signal')
            xlabel(app.UIAxes9_2, 'n')
            ylabel(app.UIAxes9_2, 'Y')
            zlabel(app.UIAxes9_2, 'Z')
            app.SignalLabel = uilabel(app.FilteringTab);
            app.SignalLabel.Text = 'Signal:';
            app.SignalDropDown = uidropdown(app.FilteringTab);
            app.SignalDropDown.Items = {};
            app.SignalDropDown.ClickedFcn = createCallbackFcn(app, @SignalDropDownClicked, true);
            app.SignalDropDown.Value = {};
            app.FrequencySignalEditFieldLabel = uilabel(app.FilteringTab);
            app.FrequencySignalEditFieldLabel.Text = 'Frequency Signal';
            app.FrequencySignalEditField = uieditfield(app.FilteringTab, 'numeric');
            app.SizeSignalEditFieldLabel = uilabel(app.FilteringTab);
            app.SizeSignalEditFieldLabel.Text = 'Size Signal';
            app.SizeSignalEditField = uieditfield(app.FilteringTab, 'numeric');
            app.PlaySignalButton = uibutton(app.FilteringTab, 'push');
            app.PlaySignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySignalButtonPushed, true);
            app.PlaySignalButton.Icon = fullfile(pathToMLAPP, 'P.png');
            app.PlaySignalButton.Text = 'Play Signal';
            app.StopSignalButton = uibutton(app.FilteringTab, 'push');
            app.StopSignalButton.ButtonPushedFcn = createCallbackFcn(app, @StopSignalButtonPushed, true);
            app.StopSignalButton.Icon = fullfile(pathToMLAPP, 'st.png');
            app.StopSignalButton.Text = 'Stop Signal';
            app.GenerateButton_5 = uibutton(app.FilteringTab, 'push');
            app.GenerateButton_5.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_5Pushed, true);
            app.GenerateButton_5.Text = 'Generate';
            app.PlayOutputButton_2 = uibutton(app.FilteringTab, 'push');
            app.PlayOutputButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayOutputButton_2Pushed, true);
            app.PlayOutputButton_2.Icon = fullfile(pathToMLAPP, 'P.png');
            app.PlayOutputButton_2.Text = 'Play Output';
            app.StopOutputButton = uibutton(app.FilteringTab, 'push');
            app.StopOutputButton.ButtonPushedFcn = createCallbackFcn(app, @StopOutputButtonPushed, true);
            app.StopOutputButton.Icon = fullfile(pathToMLAPP, 'st.png');
            app.StopOutputButton.Text = 'Stop Output';
            app.FIRhnButton = uibutton(app.FilteringTab, 'push');
            app.FIRhnButton.ButtonPushedFcn = createCallbackFcn(app, @FIRhnButtonPushed, true);
            app.FIRhnButton.Enable = 'off';
            app.FIRhnButton.Text = 'FIR hn';
            app.IIRakbkButton = uibutton(app.FilteringTab, 'push');
            app.IIRakbkButton.ButtonPushedFcn = createCallbackFcn(app, @IIRakbkButtonPushed, true);
            app.IIRakbkButton.Enable = 'off';
            app.IIRakbkButton.Text = 'IIR - a(k),b(k)';
            app.SizeWindowEditFieldLabel = uilabel(app.FilteringTab);
            app.SizeWindowEditFieldLabel.Text = 'Size Window';
            app.SizeWindowEditField = uieditfield(app.FilteringTab, 'numeric');
            app.SizeWindowEditField.Value = 500;
            app.FilterTypeDropDown = uidropdown(app.FilteringTab);
            app.FilterTypeDropDown.Items = {'FIR 1', 'FIR 2', 'FIR 3', 'FIR 4', 'FIR hn', 'IIR 1', 'IIR 2', 'IIR 3', 'IIR 4', 'IIR k'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = 'FIR 1';
            app.FilterTypeDropDownLabel = uilabel(app.FilteringTab);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FourierTab_2 = uitab(app.TabGroup);
            app.FourierTab_2.Title = 'Fourier';
            app.UIAxes5 = uiaxes(app.FourierTab_2);
            title(app.UIAxes5, 'Fourier Transform')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Magnitude')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes10 = uiaxes(app.FourierTab_2);
            title(app.UIAxes10, 'Fourier Transform whith noise')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes5_2 = uiaxes(app.FourierTab_2);
            title(app.UIAxes5_2, 'Input spectrogram')
            xlabel(app.UIAxes5_2, 'Time (s)')
            ylabel(app.UIAxes5_2, 'Frequency (Hz)')
            zlabel(app.UIAxes5_2, 'Z')
            app.UIAxes11 = uiaxes(app.FourierTab_2);
            title(app.UIAxes11, 'Output spectrogram')
            xlabel(app.UIAxes11, 'Time (s)')
            ylabel(app.UIAxes11, 'Frequency (Hz)')
            zlabel(app.UIAxes11, 'Z')
            app.FourierTransformButton = uibutton(app.FourierTab_2, 'push');
            app.FourierTransformButton.ButtonPushedFcn = createCallbackFcn(app, @FourierTransformButtonPushed, true);
            app.FourierTransformButton.Text = 'Fourier Transform';
            app.SpectumINButton = uibutton(app.FourierTab_2, 'push');
            app.SpectumINButton.ButtonPushedFcn = createCallbackFcn(app, @SpectumINButtonPushed, true);
            app.SpectumINButton.Text = 'Spectum IN';
            app.SpectrumOUTButton = uibutton(app.FourierTab_2, 'push');
            app.SpectrumOUTButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumOUTButtonPushed, true);
            app.SpectrumOUTButton.Text = 'Spectrum OUT';
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.UIAxes6 = uiaxes(app.ProcessingTab);
            title(app.UIAxes6, 'Signal 1')
            xlabel(app.UIAxes6, 'Time')
            ylabel(app.UIAxes6, 'Amplitude')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.ProcessingTab);
            title(app.UIAxes7, 'Signal 2')
            xlabel(app.UIAxes7, 'Time')
            ylabel(app.UIAxes7, 'Amplitude')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.ProcessingTab);
            title(app.UIAxes8, 'Signal Output')
            xlabel(app.UIAxes8, 'Time')
            ylabel(app.UIAxes8, 'Amplitud')
            zlabel(app.UIAxes8, 'Z')
            app.Signal1Label_2 = uilabel(app.ProcessingTab);
            app.Signal1Label_2.Text = 'Signal 1:';
            app.Signal1DropDown_2 = uidropdown(app.ProcessingTab);
            app.Signal1DropDown_2.Items = {};
            app.Signal1DropDown_2.ClickedFcn = createCallbackFcn(app, @Signal1DropDown_2Clicked, true);
            app.Signal1DropDown_2.Value = {};
            app.Signal2Label = uilabel(app.ProcessingTab);
            app.Signal2Label.Text = 'Signal 2:';
            app.Signal2DropDown_2 = uidropdown(app.ProcessingTab);
            app.Signal2DropDown_2.Items = {};
            app.Signal2DropDown_2.ClickedFcn = createCallbackFcn(app, @Signal2DropDown_2Clicked, true);
            app.Signal2DropDown_2.Value = {};
            app.SourceDropDownLabel = uilabel(app.ProcessingTab);
            app.SourceDropDownLabel.Text = 'Source:';
            app.SourceDropDown = uidropdown(app.ProcessingTab);
            app.SourceDropDown.Items = {'Signal 1', 'Signal 2'};
            app.SourceDropDown.Value = 'Signal 1';
            app.Label_3 = uilabel(app.ProcessingTab);
            app.Label_3.Text = '_____________________________';
            app.UITable3 = uitable(app.ProcessingTab);
            app.UITable3.ColumnName = {'Dimention'; 'Frequency'};
            app.UITable3.RowName = {};
            app.UITable4 = uitable(app.ProcessingTab);
            app.UITable4.ColumnName = {'Dimention'; 'Frequency'};
            app.UITable4.RowName = {};
            app.TabGroup2 = uitabgroup(app.ProcessingTab);
            app.PreprocessTab = uitab(app.TabGroup2);
            app.PreprocessTab.Title = 'Preprocess';
            app.AdvanceButton = uibutton(app.PreprocessTab, 'push');
            app.AdvanceButton.ButtonPushedFcn = createCallbackFcn(app, @AdvanceButtonPushed, true);
            app.AdvanceButton.Text = 'Advance';
            app.DisplaceinthetimeLabel = uilabel(app.PreprocessTab);
            app.DisplaceinthetimeLabel.Text = 'Displace in the time:';
            app.DelayButton = uibutton(app.PreprocessTab, 'push');
            app.DelayButton.ButtonPushedFcn = createCallbackFcn(app, @DelayButtonPushed, true);
            app.DelayButton.Text = 'Delay';
            app.NormalizeButton = uibutton(app.PreprocessTab, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Text = 'Normalize';
            app.TimeEditFieldLabel = uilabel(app.PreprocessTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.PreprocessTab, 'numeric');
            app.Label_4 = uilabel(app.PreprocessTab);
            app.Label_4.Text = '_____________________________';
            app.OperationsTab = uitab(app.TabGroup2);
            app.OperationsTab.Title = 'Operations';
            app.AddSignalsButton = uibutton(app.OperationsTab, 'push');
            app.AddSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @AddSignalsButtonPushed, true);
            app.AddSignalsButton.Text = 'Add Signals';
            app.MultiplySignalsButton = uibutton(app.OperationsTab, 'push');
            app.MultiplySignalsButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplySignalsButtonPushed, true);
            app.MultiplySignalsButton.Text = 'Multiply Signals';
            app.ResizeButton = uibutton(app.OperationsTab, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.Label_5 = uilabel(app.OperationsTab);
            app.Label_5.Text = '_____________________________';
            app.FrecuencyEditFieldLabel = uilabel(app.OperationsTab);
            app.FrecuencyEditFieldLabel.Text = 'Frecuency';
            app.FrecuencyEditField = uieditfield(app.OperationsTab, 'numeric');
            app.Label_6 = uilabel(app.OperationsTab);
            app.Label_6.Text = '_____________________________';
            app.ResamplingButton = uibutton(app.OperationsTab, 'push');
            app.ResamplingButton.ButtonPushedFcn = createCallbackFcn(app, @ResamplingButtonPushed, true);
            app.ResamplingButton.Text = 'Resampling';
            app.AdjustVolumeTab = uitab(app.TabGroup2);
            app.AdjustVolumeTab.Title = 'Adjust Volume';
            app.ConstantKnobLabel = uilabel(app.AdjustVolumeTab);
            app.ConstantKnobLabel.Text = 'Constant';
            app.ConstantKnob = uiknob(app.AdjustVolumeTab, 'continuous');
            app.ConstantKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.ConstantKnob.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.ConstantKnob.ValueChangingFcn = createCallbackFcn(app, @ConstantKnobValueChanging, true);
            app.ConstantKnob.Value = 100;
            app.LinearSwitchLabel = uilabel(app.AdjustVolumeTab);
            app.LinearSwitchLabel.Text = 'Linear';
            app.LinearSwitch = uiswitch(app.AdjustVolumeTab, 'rocker');
            app.LinearSwitch.Items = {'Down', 'Up'};
            app.LinearSwitch.Value = 'Down';
            app.AdjustVolumeLinearlyButton = uibutton(app.AdjustVolumeTab, 'push');
            app.AdjustVolumeLinearlyButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustVolumeLinearlyButtonPushed, true);
            app.AdjustVolumeLinearlyButton.Icon = fullfile(pathToMLAPP, 'vo.png');
            app.AdjustVolumeLinearlyButton.Text = 'Adjust Volume Linearly';
            app.MonoStereoTab = uitab(app.TabGroup2);
            app.MonoStereoTab.Title = 'Mono / Stereo';
            app.GenerateButton_2 = uibutton(app.MonoStereoTab, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_2Pushed, true);
            app.GenerateButton_2.Text = 'Generate';
            app.StereotoMonoLabel = uilabel(app.MonoStereoTab);
            app.StereotoMonoLabel.Text = 'Stereo to Mono:';
            app.MonotoStereoLabel = uilabel(app.MonoStereoTab);
            app.MonotoStereoLabel.Text = 'Mono to Stereo:';
            app.GenerateButton_3 = uibutton(app.MonoStereoTab, 'push');
            app.GenerateButton_3.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_3Pushed, true);
            app.GenerateButton_3.Text = 'Generate';
            app.OneSignalLabel = uilabel(app.MonoStereoTab);
            app.OneSignalLabel.Text = 'One Signal:';
            app.Label_7 = uilabel(app.MonoStereoTab);
            app.Label_7.Text = '_____________________________';
            app.TwoSignalsMonotoStereoLabel = uilabel(app.MonoStereoTab);
            app.TwoSignalsMonotoStereoLabel.Text = 'Two Signals Mono to Stereo:';
            app.GenerateButton_4 = uibutton(app.MonoStereoTab, 'push');
            app.GenerateButton_4.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_4Pushed, true);
            app.GenerateButton_4.Text = 'Generate';
            app.VerifyNANINFTab = uitab(app.TabGroup2);
            app.VerifyNANINFTab.Title = 'Verify NAN / INF';
            app.NANButton = uibutton(app.VerifyNANINFTab, 'push');
            app.NANButton.ButtonPushedFcn = createCallbackFcn(app, @NANButtonPushed, true);
            app.NANButton.Text = 'NAN';
            app.INFButton = uibutton(app.VerifyNANINFTab, 'push');
            app.INFButton.ButtonPushedFcn = createCallbackFcn(app, @INFButtonPushed, true);
            app.INFButton.Text = 'INF';
            app.EliminateButton = uibutton(app.VerifyNANINFTab, 'push');
            app.EliminateButton.ButtonPushedFcn = createCallbackFcn(app, @EliminateButtonPushed, true);
            app.EliminateButton.Icon = fullfile(pathToMLAPP, 'eli.png');
            app.EliminateButton.Text = 'Eliminate';
            app.EditField = uieditfield(app.VerifyNANINFTab, 'text');
            app.PlayButton_3 = uibutton(app.ProcessingTab, 'push');
            app.PlayButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_3Pushed, true);
            app.PlayButton_3.Icon = fullfile(pathToMLAPP, 'P.png');
            app.PlayButton_3.Text = 'Play';
            app.ReversePlayButton = uibutton(app.ProcessingTab, 'push');
            app.ReversePlayButton.ButtonPushedFcn = createCallbackFcn(app, @ReversePlayButtonPushed, true);
            app.ReversePlayButton.Icon = fullfile(pathToMLAPP, 'reverse.png');
            app.ReversePlayButton.Text = 'Reverse Play';
            app.StopButton_2 = uibutton(app.ProcessingTab, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Icon = fullfile(pathToMLAPP, 'st.png');
            app.StopButton_2.Text = 'Stop';
            app.PlayOutputButton = uibutton(app.ProcessingTab, 'push');
            app.PlayOutputButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOutputButtonPushed, true);
            app.PlayOutputButton.Icon = fullfile(pathToMLAPP, 'P.png');
            app.PlayOutputButton.Text = 'Play Output';
            app.SaveOutputButton = uibutton(app.ProcessingTab, 'push');
            app.SaveOutputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputButtonPushed, true);
            app.SaveOutputButton.Icon = fullfile(pathToMLAPP, 'sav.png');
            app.SaveOutputButton.Text = 'Save Output';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.stProjectDigitalSignalProcessingLabel = uilabel(app.AboutTab);
            app.stProjectDigitalSignalProcessingLabel.Text = '1st Project - Digital Signal Processing';
            app.LisdeyFabianaPeaBohorquez1840838Label = uilabel(app.AboutTab);
            app.LisdeyFabianaPeaBohorquez1840838Label.Text = 'Lisdey Fabiana Peña Bohorquez - 1840838';
            app.MillerEduardoOrtizMelo1746911Label = uilabel(app.AboutTab);
            app.MillerEduardoOrtizMelo1746911Label.Text = 'Miller Eduardo Ortiz Melo - 1746911';
            app.LuisEduardoRodriguezOrtiz1940527Label = uilabel(app.AboutTab);
            app.LuisEduardoRodriguezOrtiz1940527Label.Text = 'Luis Eduardo Rodriguez Ortiz - 1940527';
            app.UniversidaddelValleLabel = uilabel(app.AboutTab);
            app.UniversidaddelValleLabel.Text = 'Universidad del Valle';
            app.CaliColombiaLabel = uilabel(app.AboutTab);
            app.CaliColombiaLabel.Text = 'Cali, Colombia';
            app.Nov2023Label = uilabel(app.AboutTab);
            app.Nov2023Label.Text = '21 / Nov / 2023';
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, '70_Rojo.jpg');
            app.Label_8 = uilabel(app.AboutTab);
            app.Label_8.Text = '_____________________________________';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proyecto1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end